classdef RedPi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        LOOnButton       matlab.ui.control.Button
        BurstONButton    matlab.ui.control.Button
        ReconnectButton  matlab.ui.control.Button
        RightPanel       matlab.ui.container.Panel
        RefreshButton    matlab.ui.control.Button
        UIAxes4          matlab.ui.control.UIAxes
        UIAxes3          matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
            
            %% Define Red Pitaya as TCP/IP object
            tcpipObj=tcpclient('169.254.182.46', 5000); %tcpip pas tcpclient avant
            f_decal=2500;
            f_Tx = 23000000;
            f_LO = 23003000;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Define Red Pitaya as TCP/IP object
            IP= '169.254.182.46';           % Input IP of your Red Pitaya...
            port = 5000;
            tcpipObj=tcpclient(IP, port); %tcpip pas tcpclient avant
           % app.tcpipObj.NumBytesAvailable = 16384*128;
            close all
            %% Open connection with your Red Pitaya
            %fopen(app.tcpipObj);
            configureTerminator(tcpipObj,"CR/LF")
            %app.tcpipObj.Terminator = 'CR/LF';
            flush(tcpipObj);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
        figure
        plot(app.UIAxes4,x_axis,signal_num)
        grid on
        ylabel(app.UIAxes4,'Voltage(V)')
        xlabel(app.UIAxes4,'Time(s)')
        %hold off
        figure
        plot(app.UIAxes3,fgrid,FFT_abs);
        ylabel(app.UIAxes3,'power / ?')
        xlabel(app.UIAxes3,'frequencies')
        grid on
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
         %% Define Red Pitaya as TCP/IP object
            IP= '169.254.182.46';           % Input IP of your Red Pitaya...
            port = 5000;
            tcpipObj=tcpclient(IP, port); %tcpip pas tcpclient avant
           % tcpipObj.NumBytesAvailable = 16384*128;
            close all
            %% Open connection with your Red Pitaya
            %fopen(tcpipObj);
            configureTerminator(tcpipObj,"CR/LF")
            %tcpipObj.Terminator = 'CR/LF';
            flush(tcpipObj);
        end

        % Button pushed function: BurstONButton
        function BurstONButtonPushed(app, event)
            f_Tx = 24359200; %HW.fLarmor;
            f_LO = f_Tx+1000;
            tcpipObj=app.tcpipObj;
            writeline(app.tcpipObj,"GEN:RST");
            configureTerminator(app.tcpipObj,"CR/LF")
            %% Receive on channel 1
            %Set acquisition
            writeline(app.tcpipObj,'ACQ:RST');
            writeline(app.tcpipObj,'ACQ:DEC 8192');  %decimation factor
            writeline(app.tcpipObj,'ACQ:AVG OFF');    %disable averaging
            writeline(app.tcpipObj,'ACQ:TRIG:LEV 0.1');  %triger level in mV
            writeline(app.tcpipObj,'ACQ:SOUR1:GAIN LV'); %source and gain HV or LV


            writeline(app.tcpipObj,'ACQ:START');
            % Start measurement
            %[Raw, SeqOut, data, data_1D] = set_sequence(HW, Seq, AQ, TX, Grad);
            % writeline(app.tcpipObj,'OUTPUT1:STATE ON');        % Set output to ON
            pause(0.7);
            writeline(app.tcpipObj,'ACQ:TRIG NOW');

            while 1
                trig_rsp=writeread(tcpipObj,"ACQ:TRIG:STAT?")
                writeline(tcpipObj,"DIG:PIN LED3,1")
                if strcmp('TD',trig_rsp(1:2))  % Read only TD
                    break
                end
                writeline(app.tcpipObj,'DIG:PIN LED3,0')
            end
            writeline(app.tcpipObj,'DIG:PIN LED3,0');

            signal_str=writeread(app.tcpipObj,"ACQ:SOUR1:DATA?"); %read data from buffer
            % Convert values to numbers.% First character in string is “{“
            % and 2 latest are empty spaces and last is “}”.

            signal_num=str2double(signal_str(1,2:length(signal_str)-3)); %str2num par default pas str2double
            sample_number = length(signal_num)-1;
            x_axis_raw= (0:1:sample_number);
            fs=15.258e3; %sampling rate/frequency See decimation factor !!!
            x_axis= x_axis_raw./fs; %sample number / Fs, for Fs see decimation factor
            %FFT
            FFT_signal=fft(signal_num);
            FFT_abs=abs(FFT_signal);
            N = length(FFT_abs);
            fgrid = fs*(0:(N-1))/(N);
            FFT_abs = FFT_abs(100:floor(N/4));
            fgrid = fgrid(100:floor(N/4));
        end

        % Button pushed function: LOOnButton
        function LOOnButtonPushed(app, event)
            writeline(app.tcpipObj,'SOUR2:FUNC SINE');       % Set function of output signal {sine, square, triangle, sawu,sawd, pwm} 24381696
            LO_f = sprintf('SOUR2:FREQ:FIX %f',f_LO);% Set frequency of output signal
            writeline(app.tcpipObj, LO_f);
            writeline(app.tcpipObj,'SOUR2:VOLT 1');         % Set amplitude of output signal
            writeline(app.tcpipObj,'SOUR2:VOLT:OFFS 0');
            writeline(app.tcpipObj,'OUTPUT2:STATE ON');        % Set output to ON
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {699, 699};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {271, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReconnectButton = uibutton(app.LeftPanel, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Text = 'Reconnect';
            app.BurstONButton = uibutton(app.LeftPanel, 'push');
            app.BurstONButton.ButtonPushedFcn = createCallbackFcn(app, @BurstONButtonPushed, true);
            app.BurstONButton.Text = 'Burst ON';
            app.LOOnButton = uibutton(app.LeftPanel, 'push');
            app.LOOnButton.ButtonPushedFcn = createCallbackFcn(app, @LOOnButtonPushed, true);
            app.LOOnButton.Text = 'LO On';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'FID')
            xlabel(app.UIAxes, 'Time (t)')
            ylabel(app.UIAxes, 'Amplitude (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Spectrum')
            xlabel(app.UIAxes2, 'Chemical Shift (ppm)')
            ylabel(app.UIAxes2, 'Power density (V2/Hz)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'FID Pure Device')
            xlabel(app.UIAxes3, 'Time (t)')
            ylabel(app.UIAxes3, 'Amplitude (V)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Spectrum')
            xlabel(app.UIAxes4, 'Chemical shift (ppm)')
            ylabel(app.UIAxes4, 'Power density (V2/Hz)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.RefreshButton = uibutton(app.RightPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RedPi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end