classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        OffButton                      matlab.ui.control.Button
        StepP1                         matlab.ui.control.EditField
        StepEditField_2Label           matlab.ui.control.Label
        StepP0                         matlab.ui.control.EditField
        StepEditFieldLabel             matlab.ui.control.Label
        OrderP0                        matlab.ui.control.EditField
        OrderEditFieldLabel            matlab.ui.control.Label
        OrderP1                        matlab.ui.control.EditField
        stOrderEditFieldLabel          matlab.ui.control.Label
        PhasingLabel                   matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        ReadyLampLabel                 matlab.ui.control.Label
        RefreshSystemButton            matlab.ui.control.Button
        StartPureDeviceButton          matlab.ui.control.StateButton
        Lamp_PD                        matlab.ui.control.Lamp
        IfPureDevicenotstartedRPTxnotusedRPonlyLabel  matlab.ui.control.Label
        SendTxRPOnlyButton             matlab.ui.control.Button
        VLabel                         matlab.ui.control.Label
        musLabel                       matlab.ui.control.Label
        RxSettingsLabel                matlab.ui.control.Label
        TxtimetEditField               matlab.ui.control.NumericEditField
        TxtimetEditFieldLabel          matlab.ui.control.Label
        AverageSpinner                 matlab.ui.control.Spinner
        AverageSpinnerLabel            matlab.ui.control.Label
        TxVEditField                   matlab.ui.control.NumericEditField
        TxVEditFieldLabel              matlab.ui.control.Label
        LarmorFrequencyHzSpinner       matlab.ui.control.Spinner
        LarmorFrequencyHzSpinnerLabel  matlab.ui.control.Label
        MixershiftfrequencyHzSlider    matlab.ui.control.Slider
        MixershiftfrequencyHzSliderLabel  matlab.ui.control.Label
        Lamp_loop                      matlab.ui.control.Lamp
        StartMeasurementButton         matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        LOonoffButton                  matlab.ui.control.StateButton
        ValidateLOandLFvaluesButton    matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        AxeCTRL                        matlab.ui.control.UIAxes
        AxePhase                       matlab.ui.control.UIAxes
        AxeAVRCOMP                     matlab.ui.control.UIAxes
        AxeFFT                         matlab.ui.control.UIAxes
        AxeReceived                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Data % Description
        f_LO %local oscillator value
        V_LO % Local oscillator amplitude
        f_shift %frequency shift of the mixer (f_tx+f_shift)
        f_Tx %Larmor Tx frequency 24359200
        V_Tx %Tx Value
        Tx_time %Transmission time of RX
        rp % client redpitaya
        flag_Tx % Tx flag for the pure device Tx trigger
        max_loop % Number of loop for averaging
        FFT_abs % Rx Buffer
        fgrid % x axis of fft windows
        IP % IP adress
        HW % Pure Device Hardware call
        y_stack % Matrix for FID correlation and stacking
        y_trc %for truncating FID
        crop_length % Fid maximum truncation
        y1 % Based FID for first alignement
        y3 % Based FID for truncation and comparison
        fid_base_complex % For saving the first FID but hilbert transformed
        Y_base_real % real part of the FFT of the fid base complex
        Y_real % same as before, real part of the FFT hibert transformed, but for multiple iteration of FID
        lgd %for legend of the FFT phased
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('C:\Users\Vinh\Desktop\openMatlab')
            app.IP= '169.254.182.46';           % Input IP of your Red Pitaya... 169.254.182.46
            port = 5000;
            app.rp=tcpclient(app.IP, port); %tcpip pas tcpclient avant
            %% Open connection with your Red Pitaya
            app.rp.ByteOrder = "big-endian";
            configureTerminator(app.rp,"CR/LF");

            fopen(app.rp);

            flush(app.rp,"input")
            flush(app.rp,"output")
            writeline(app.rp,'DIG:PIN LED1,1');
            writeline(app.rp,'GEN:RST'); % Reset the generator parameters

            %Value init
            app.V_LO = 0.2; %default 1
            app.f_Tx = 24359200;
            app.V_Tx = 0.3;

            app.f_shift = 1000;
            app.max_loop= 1;
            app.Tx_time = 17e-6;
            app.f_LO = app.f_Tx+app.f_shift; %Mixer frequency
            app.AverageSpinner.Value = app.max_loop;

            app.AverageSpinner.Value = 1;
            app.MixershiftfrequencyHzSlider.Value = app.f_shift;
            app.LarmorFrequencyHzSpinner.Value = app.f_Tx;

            writeline(app.rp,'DIG:PIN LED1,0');
            writeline(app.rp,'DIG:PIN LED2,0');
            writeline(app.rp,'DIG:PIN LED3,0');
            writeline(app.rp,'DIG:PIN LED4,0');
            writeline(app.rp,'DIG:PIN LED5,0');
            writeline(app.rp,'DIG:PIN LED6,0');
            writeline(app.rp,'DIG:PIN LED7,0');

            cla(app.AxeCTRL);
            cla(app.AxePhase);
            cla(app.AxeAVRCOMP);
            cla(app.AxeFFT);
            cla(app.AxeReceived);

            app.y1 = [];
            app.y3 = [];
        end

        % Button pushed function: ValidateLOandLFvaluesButton
        function ValidateLOandLFvaluesButtonPushed(app, event)
            app.f_LO = app.f_Tx+app.f_shift;
            app.f_LO = uint64(app.f_LO);
            display_Tx = ['Larmor frequency is ', num2str(app.f_Tx),'Hz.'];
            disp(display_Tx);
            display_LO = ['LO frequency is ', num2str(app.f_LO),'Hz.'];
            disp(display_LO);
        end

        % Value changed function: MixershiftfrequencyHzSlider
        function MixershiftfrequencyHzSliderValueChanged(app, event)
            app.f_shift = app.MixershiftfrequencyHzSlider.Value;
        end

        % Value changed function: LarmorFrequencyHzSpinner
        function LarmorFrequencyHzSpinnerValueChanged(app, event)
            app.f_Tx = app.LarmorFrequencyHzSpinner.Value;
        end

        % Value changed function: LOonoffButton
        function LOonoffButtonValueChanged(app, event)
            Start_LO = app.LOonoffButton.Value;
            %             app.rp.Terminator;
            writeline(app.rp,'DIG:PIN LED2,1');
            writeline(app.rp,'SOUR2:FUNC SINE');
            LO_f = sprintf('SOUR2:FREQ:FIX %f',app.f_LO);% Set frequency of output signal
            writeline(app.rp, LO_f);
            LO_Amp = sprintf('SOUR2:VOLT %f',app.V_LO); % Amplitude value of Source 2
            writeline(app.rp, LO_Amp);         % Set amplitude of output signal
            writeline(app.rp,'SOUR2:VOLT:OFFS 0');

            %disp('LO Ready')
            if Start_LO == 1
                writeline(app.rp,'OUTPUT2:STATE ON');
                app.Lamp.Color = 'y';
                disp('LO started')
            else
                writeline(app.rp,'OUTPUT2:STATE OFF');
                disp('LO stopped')
                app.Lamp.Color = 'g';
                writeline(app.rp,'DIG:PIN LED2,0');
            end

        end

        % Button pushed function: StartMeasurementButton
        function StartMeasurementButtonPushed(app, event)

            cla(app.AxeCTRL);
            cla(app.AxePhase);
            cla(app.AxeAVRCOMP);
            cla(app.AxeFFT);
            cla(app.AxeReceived);
            app.y_stack = [];
            %            [Raw, SeqOut, data, data_1D] = set_sequence(HW, Seq, AQ, TX, Grad);

            %% Receive on channel 1


            %Set acquisition
            writeline(app.rp,'ACQ:RST');
            writeline(app.rp,'ACQ:DEC 8192');  %decimation factor
            writeline(app.rp,'ACQ:AVG OFF');    %disable averaging
            writeline(app.rp,'ACQ:TRIG:LEV 0.1');  %triger level in mV
            writeline(app.rp,'ACQ:SOUR1:GAIN LV'); %source and gain HV or LV

            for n = 1:app.max_loop
                app.Lamp_loop.Color = 'b';
                writeline(app.rp,'ACQ:START');
                % Start measurement
                if app.flag_Tx == true %If pure device started then :
                    LoadSystem;
                    app.Lamp_PD.Color = 'y';
                    [Raw, SeqOut, data, data_1D] = set_sequence(HW, Seq, AQ, TX, Grad);

                    %writeline(app.rp,'DIG:PIN LED1,1');

                else
                    app.Lamp_loop.Color = 'y';
                    app.Lamp_PD.Color = 'black';
                    %app.flag_Tx = false;
                    % writeline(app.rp,'DIG:PIN LED1,0');
                    %SendTxRPOnlyButtonPushed(app); %avoid due to timing constraint

                    writeline(app.rp,'SOUR1:FUNC SINE');
                    Tx_f = sprintf('SOUR1:FREQ:FIX %f',app.f_Tx);% Set frequency of output signal
                    writeline(app.rp, Tx_f);
                    Tx_Amp = sprintf('SOUR1:VOLT %f',app.V_Tx); % Amplitude value of Source 2
                    writeline(app.rp, Tx_Amp);         % Set amplitude of output signal
                    %writeline(app.rp,'SOUR1:VOLT:OFFS 0');
                    Tx_length = app.Tx_time * 100/4.05e-6;
                    writeline(app.rp,'SOUR1:BURS:STAT ON');      % Set burst mode to ON
                    Burston = sprintf('SOUR1:BURS:NCYC %f',Tx_length); % Conversion to string for burst timing
                    writeline(app.rp, Burston);       % Set Tx_length to pulses of sine wave
                    writeline(app.rp,'DIG:PIN LED3,1');
                    writeline(app.rp,'OUTPUT1:STATE ON'); % Set output to ON for Tx from
                end
                pause(0.65);
                writeline(app.rp,'ACQ:TRIG NOW');

                while 1
                    trig_rsp=writeread(app.rp,"ACQ:TRIG:STAT?");

                    if strcmp('TD',trig_rsp)  % Read only TD
                        break
                    end
                end


                signal_str=writeread(app.rp,"ACQ:SOUR1:DATA?");
                fprintf('Receiving  %d done.\n',n)
                writeline(app.rp,'DIG:PIN LED3,0');
                writeline(app.rp,'OUTPUT1:STATE OFF'); % Set output to ON for Tx from

                L=strlength(signal_str);
                charo = convertStringsToChars(signal_str(1));
                signal_num=str2num(charo(1,2:L-2));
                %%             To avoid error do not use the following is inside the designer app
                %             signal_num=str2num(signal_str(1,2:length(signal_str)-3)); %str2num par default pas str2double
                sample_number = length(signal_num)-1;
                x_axis_raw= (0:1:sample_number);


                %% Data set
                Fs=15.258e3; %sampling rate/frequency See decimation factor of the RedPitaya !!!
                T = 1/Fs;   % Sampling period

                t  = x_axis_raw./Fs; %sample number / Fs, for Fs see decimation factor % Time vector
                fid = signal_num;
                L = length(fid);             % Length of signal

                app.fgrid=Fs/L*(-L/2:L/2-1); %frequency axis

                %% Create the imaginary part

                S.data_y(:,n) = hilbert(fid); % Using hilbert transform we have the 90 Phase signal (Quadrature) en the real part
                %inside column

                %             %signal_num=(signal_str(1,2:length(signal_str)-3)); %str2num par default pas str2double
                %             sample_number = length(signal_num)-1;
                %             x_axis_raw= (0:1:sample_number);
                %             fs=15.258e3; %sampling rate/frequency See decimation factor !!!
                %             x_axis= x_axis_raw./fs; %sample number / Fs, for Fs see decimation factor
                %             %FFT
                %             FFT_signal=fft(signal_num);
                %             app.FFT_abs=abs(FFT_signal);
                %             N = length(app.FFT_abs);

                %app.fgrid = fs*(0:(N-1))/(N);
                %             app.FFT_abs = app.FFT_abs(100:ceil(N/4));
                %             size(app.FFT_abs);
                %             FFT_loop(n,:) = app.FFT_abs; %savin on each line
            end
            %% Make and Separate the different FFT from the complex FID

            S.Y_real = real(fftshift(fft((S.data_y))));
            S.Y_imag = imag(fftshift(fft((S.data_y))));
            S.Y_abs = abs(fftshift(fft((S.data_y))));

            %% FID Plotting time !

            hold(app.AxeReceived, 'on');
            plot(app.AxeReceived,t,real(S.data_y));
            plot(app.AxeReceived,t,imag(S.data_y));
            plot(app.AxeReceived,t,abs(S.data_y));
            hold(app.AxeReceived, 'off');
            legend(app.AxeReceived,'Real','Imaginary','Abs');
            app.fgrid = ((app.fgrid*1e6)/(app.f_LO));
            %% FFT Plotting time !

            hold(app.AxeFFT, 'on');
            plot(app.AxeFFT,t,real(S.Y_real));
            plot(app.AxeFFT,t,imag(S.Y_imag));
            plot(app.AxeFFT,t,abs(S.Y_abs));
            hold(app.AxeFFT, 'off');
            legend(app.AxeFFT,'Real','Imaginary','Abs');
            disp('Total Receiving done');
            app.Lamp_loop.Color = 'g';

            %% START CORRELATION AVERAGING with only real part
            i=0;
            for i=1:app.max_loop
                [psor,lsor] = findpeaks(real(S.data_y(:,i)),'SortStr','descend'); %psor position lsor location
                %%%%findpeaks(real(S.data_y(:,i))); %if we want to see the
                %%%%graph
                %text(lsor+.02,psor,num2str((1:numel(psor))')) show peaks in figure
                First_Index = lsor(2); % Choix du pic référence
                Max_Index=length(real(S.data_y(:,i))); %Derniere valeur de S.data_y(i)
                app.crop_length=Max_Index-First_Index; %Obligatoire pour éviter l'erreur d'allocation quand S.data_crop(:,i) laissé vide
                S.data_crop(1:app.crop_length,i) = real(S.data_y(First_Index:Max_Index-1,i));
                            app.ReadyLamp.Color = 'y';
            end
            i=0;
            %% Alignement and mean

            for i=1:app.max_loop
                if i==1
                    app.y1 = S.data_crop(:,1); %S(1).data(:,2);
                    %y1 = bandpass(y1,[200 3000],Fs);
                       %       app.ReadyLamp.Color = 'blue'; %control lamp

                else

                    [app.y1,y2] = alignsignals(app.y1,S.data_crop(:,i),Method="xcorr");

                    %         remettre dans une variable
                    %         resynchro avec une nouvelle
                    app.y_stack(:,1)=app.y1(1:app.crop_length);                    
                    %y2=y2(1:app.crop_length);
                    app.y_stack(:,i)=y2(1:app.crop_length);
                end

            end
            y_mean=mean(app.y_stack,2); %mean of each row = 2 , line =1

            %% Plot of averaging truncated FID
            if app.AverageSpinner.Value > 1
                Y_nbr=1; %which ymean we want to see
                yz= app.y_stack(:,Y_nbr);
                Truncate_sample=10; %%%%% To change, sample number from which we want to truncate
                app.y_trc = y_mean(Truncate_sample:length(y_mean));
                y_test= yz(Truncate_sample:length(yz));
                Tz=1:1:length(app.y_trc);
                
                plot(app.AxeAVRCOMP,Tz,app.y_trc,Tz,y_test)
                legend(app.AxeAVRCOMP,'Averaging of aligned and truncated FIDs');
            elseif app.AverageSpinner.Value == 1
                Y_nbr=1; %which ymean we want to see
                Truncate_sample=10; %%%%% To change, sample number from which we want to truncate
                app.y_trc = y_mean(Truncate_sample:length(y_mean));
                yz= app.y_trc;
                y_test= yz;
                Tz=1:1:length(app.y_trc);
                
                plot(app.AxeAVRCOMP,Tz,app.y_trc)
                legend(app.AxeAVRCOMP,'Only FID');

            end

            %% Control Figure

            c_map= parula(app.max_loop);
            for k=1:app.max_loop
                [app.y3,y4] = alignsignals(S.data_crop(:,1),S.data_crop(:,k),Method="xcorr");
                plot(app.AxeCTRL,y4,'Color',c_map(k,:))
                hold(app.AxeCTRL, 'on');
                s1 = 'Figure n';
                s2 = k;
                lgd_ctrl{k} = strcat(s1, s2);
            end
            plot(app.AxeCTRL,app.y3)
            hold(app.AxeCTRL, 'off');
            legend(app.AxeCTRL,lgd_ctrl(1:app.max_loop));

            fid_base = [];
           
            % Hilbert transform & Variables %%
            Truncate_sample=1;
            L = length(app.y_trc); % Length of signal
            fid = app.y_trc;
            fid_complex = hilbert(app.y_trc);
            
            L_base = length(app.y1); %y3
            app.fgrid=Fs/L*(-L/2:L/2-1);
            fid_base = app.y1(Truncate_sample:L); %y3
            
            app.fid_base_complex =  hilbert(fid_base);

            app.fgrid=app.fgrid'; %row to colum or inverse


            %% Phasing variable

%             P0 = app.OrderP0.Value;
%             P1 = app.OrderP1.Value;
% 
%             P0_step = app.StepP0.Value;
%             P1_step = app.StepP1.Value;
% 
% 
% %% Phasing figures & FFT
%                         app.Y_base_real = real(fftshift(fft(app.fid_base_complex)));
%             
%                         for i=1:app.max_loop
%                             P1=P1+P1_step;
%             
%                             app.Y_real = real(fftshift(fft((fid_complex))).*exp(1i*app.OrderP0.Value).*exp(1i*app.OrderP1.Value*2*pi*app.fgrid));
%             
%                             %   Y_imag = imag(fftshift(fft((fid_complex))));
%                             %   .*exp(j+2*pi*fgrid*P1);
%             
%                             plot(app.AxePhase,app.fgrid,app.Y_real);
%             
%                             app.lgd{i} = strcat('P0=',num2str(app.OrderP0.Value),'and P1=',num2str(app.OrderP1.Value)) ;
%             
%                             hold(app.AxePhase, 'on');
%                         end
%             
%                         counter_theta = app.max_loop+1;
%                         plot(app.AxePhase,app.Y_base_real);
%                         app.lgd{counter_theta}=('File base');
%             
%                         hold(app.AxePhase, 'off');
%                         app.fgrid = ((app.fgrid*1e6)/(app.f_LO))-60;
%             
%                         app.ReadyLamp.Color = 'black';
%             
%                         grid(app.AxePhase,  'on');
%                         legend(app.AxePhase,app.lgd(1:length(app.lgd)));
%             

        end

        % Button down function: AxeReceived
        function AxeReceivedButtonDown(app, event)

        end

        % Button down function: AxeFFT
        function AxeFFTButtonDown(app, event)

        end

        % Value changed function: TxVEditField
        function TxVEditFieldValueChanged(app, event)
            app.V_Tx = app.TxVEditField.Value;%/(10^(32.39577/20));

        end

        % Button down function: AxeAVRCOMP
        function AxeAVRCOMPButtonDown(app, event)

        end

        % Value changed function: AverageSpinner
        function AverageSpinnerValueChanged(app, event)
            app.max_loop = app.AverageSpinner.Value;

        end

        % Value changed function: TxtimetEditField
        function TxtimetEditFieldValueChanged(app, event)
            app.Tx_time = app.TxtimetEditField.Value*1e-6;

        end

        % Button pushed function: SendTxRPOnlyButton
        function SendTxRPOnlyButtonPushed(app, event)

            writeline(app.rp,'SOUR1:FUNC SINE');
            Tx_f = sprintf('SOUR1:FREQ:FIX %f',app.f_Tx);% Set frequency of output signal
            writeline(app.rp, Tx_f);
            Tx_Amp = sprintf('SOUR1:VOLT %f',app.V_Tx); % Amplitude value of Source 2
            writeline(app.rp, Tx_Amp);         % Set amplitude of output signal
            %writeline(app.rp,'SOUR1:VOLT:OFFS 0');
            Tx_length = app.Tx_time * 100/4.05e-6;
            writeline(app.rp,'SOUR1:BURS:STAT ON');      % Set burst mode to ON
            Burston = sprintf('SOUR1:BURS:NCYC %f',Tx_length); % Conversion to string for burst timing
            writeline(app.rp, Burston);       % Set Tx_length to pulses of sine wave
            writeline(app.rp,'DIG:PIN LED3,1');
            writeline(app.rp,'OUTPUT1:STATE ON');
            disp('Tx On')
            pause(0.5); %arbitrary value

        end

        % Value changed function: StartPureDeviceButton
        function StartPureDeviceButtonValueChanged(app, event)
            app.flag_Tx = app.StartPureDeviceButton.Value;
            writeline(app.rp,'DIG:PIN LED3,1');
            if app.flag_Tx == true
                % FID
                LoadSystem;

                app.Lamp_PD.Color = 'y';
                [HW, ~] = Find_Frequency_Sweep(HW, mySave, 10, [], 1);  % find magnet frequency
                % HW.fLarmor = 20e6;                              % override Larmor frequency

                % Parameters used for timing calculations
                Seq.p90         = HW.tFlip90Def;                % duration of TX pulse
                Seq.plotSeq     = [];                           % plot sequence off

                % Sequence parameters
                Seq.tRep        = 100e-3;                       % repetition time

                % RF transmission parameters
                TX.Start        = 0;                            % start time of rf-pulse
                TX.Duration     = Seq.p90;                      % duration of rf-pulse
                TX.Frequency    = HW.fLarmor;                   % frequency of rf-pulse
                TX.Phase        = 0;                            % phase of rf-pulse

                % Acquisition parameters
                AQ.Start        = 100e-6 + Seq.p90;             % acquisition start time
                AQ.fSample      = 25e3;                         % sampling rate of AQ window
                AQ.nSamples     = 1024;                         % number of samples in AQ window
                AQ.Frequency    = HW.fLarmor;                   % frequency of AQ window
                AQ.Phase        = 0;                            % phase of AQ window
                AQ.Gain         = HW.RX(1).Amplitude2Uin / 20e-3;  % maximum input voltage
                disp('Pure Device started')
            else
                app.Lamp_PD.Color = 'black';
                writeline(app.rp,'DIG:PIN LED3,0');
                disp('Pure Device stopped')

            end
        end

        % Button pushed function: RefreshSystemButton
        function RefreshSystemButtonPushed(app, event)
            clear RP_Client;
            writeline(app.rp,'DIG:PIN LED1,0');
            startupFcn(app);


        end

        % Button down function: AxeCTRL
        function AxeCTRLButtonDown(app, event)

        end

        % Value changing function: AverageSpinner
        function AverageSpinnerValueChanging(app, event)

        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            writeline(app.rp,'OUTPUT1:STATE OFF');
            disp('Tx Off')
            writeline(app.rp,'DIG:PIN LED3,0');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {687, 687};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {286, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.ValidateLOandLFvaluesButton = uibutton(app.GridLayout2, 'push');
            app.ValidateLOandLFvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateLOandLFvaluesButtonPushed, true);
            app.ValidateLOandLFvaluesButton.Text = {'Validate LO '; 'and LF values'};
            app.LOonoffButton = uibutton(app.GridLayout2, 'state');
            app.LOonoffButton.ValueChangedFcn = createCallbackFcn(app, @LOonoffButtonValueChanged, true);
            app.LOonoffButton.Text = 'LO on/off';
            app.Lamp = uilamp(app.GridLayout2);
            app.StartMeasurementButton = uibutton(app.GridLayout2, 'push');
            app.StartMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementButtonPushed, true);
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.Lamp_loop = uilamp(app.GridLayout2);
            app.MixershiftfrequencyHzSliderLabel = uilabel(app.GridLayout2);
            app.MixershiftfrequencyHzSliderLabel.Text = 'Mixer shift frequency (Hz)';
            app.MixershiftfrequencyHzSlider = uislider(app.GridLayout2);
            app.MixershiftfrequencyHzSlider.Limits = [0 5000];
            app.MixershiftfrequencyHzSlider.MajorTicks = [0 1000 2000 3000 4000 5000];
            app.MixershiftfrequencyHzSlider.MajorTickLabels = {'0', '1000', '2000', '3000', '4000', '5000'};
            app.MixershiftfrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @MixershiftfrequencyHzSliderValueChanged, true);
            app.MixershiftfrequencyHzSlider.MinorTicks = [0 100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 1900 2000 2100 2200 2300 2400 2500 2600 2700 2800 2900 3000 3100 3200 3300 3400 3500 3600 3700 3800 3900 4000 4100 4200 4300 4400 4500 4600 4700 4800 4900 5000];
            app.MixershiftfrequencyHzSlider.Value = 1000;
            app.LarmorFrequencyHzSpinnerLabel = uilabel(app.GridLayout2);
            app.LarmorFrequencyHzSpinnerLabel.Text = 'Larmor Frequency (Hz)';
            app.LarmorFrequencyHzSpinner = uispinner(app.GridLayout2);
            app.LarmorFrequencyHzSpinner.ValueDisplayFormat = '%.3f';
            app.LarmorFrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @LarmorFrequencyHzSpinnerValueChanged, true);
            app.LarmorFrequencyHzSpinner.Value = 24359200;
            app.TxVEditFieldLabel = uilabel(app.GridLayout2);
            app.TxVEditFieldLabel.Text = 'Tx (V)';
            app.TxVEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TxVEditField.Limits = [0 15];
            app.TxVEditField.ValueChangedFcn = createCallbackFcn(app, @TxVEditFieldValueChanged, true);
            app.TxVEditField.Value = 0.138;
            app.AverageSpinnerLabel = uilabel(app.GridLayout2);
            app.AverageSpinnerLabel.Text = 'Average ';
            app.AverageSpinner = uispinner(app.GridLayout2);
            app.AverageSpinner.ValueChangingFcn = createCallbackFcn(app, @AverageSpinnerValueChanging, true);
            app.AverageSpinner.Limits = [1 Inf];
            app.AverageSpinner.ValueChangedFcn = createCallbackFcn(app, @AverageSpinnerValueChanged, true);
            app.AverageSpinner.Value = 1;
            app.TxtimetEditFieldLabel = uilabel(app.GridLayout2);
            app.TxtimetEditFieldLabel.Text = 'Tx time (t)';
            app.TxtimetEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TxtimetEditField.Limits = [0 Inf];
            app.TxtimetEditField.ValueChangedFcn = createCallbackFcn(app, @TxtimetEditFieldValueChanged, true);
            app.TxtimetEditField.Value = 44;
            app.RxSettingsLabel = uilabel(app.GridLayout2);
            app.RxSettingsLabel.Text = 'Rx Settings';
            app.musLabel = uilabel(app.GridLayout2);
            app.musLabel.Interpreter = 'latex';
            app.musLabel.Text = '$\mu s$';
            app.VLabel = uilabel(app.GridLayout2);
            app.VLabel.Interpreter = 'latex';
            app.VLabel.Text = '$V$';
            app.SendTxRPOnlyButton = uibutton(app.GridLayout2, 'push');
            app.SendTxRPOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @SendTxRPOnlyButtonPushed, true);
            app.SendTxRPOnlyButton.Text = 'Send Tx RP Only';
            app.IfPureDevicenotstartedRPTxnotusedRPonlyLabel = uilabel(app.GridLayout2);
            app.IfPureDevicenotstartedRPTxnotusedRPonlyLabel.Text = 'If PureDevice not started, RP Tx not used, RP only';
            app.Lamp_PD = uilamp(app.GridLayout2);
            app.StartPureDeviceButton = uibutton(app.GridLayout2, 'state');
            app.StartPureDeviceButton.ValueChangedFcn = createCallbackFcn(app, @StartPureDeviceButtonValueChanged, true);
            app.StartPureDeviceButton.Text = 'Start PureDevice';
            app.RefreshSystemButton = uibutton(app.GridLayout2, 'push');
            app.RefreshSystemButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshSystemButtonPushed, true);
            app.RefreshSystemButton.Text = {'Refresh'; 'System'};
            app.ReadyLampLabel = uilabel(app.GridLayout2);
            app.ReadyLampLabel.Visible = 'off';
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.GridLayout2);
            app.ReadyLamp.Visible = 'off';
            app.PhasingLabel = uilabel(app.GridLayout2);
            app.PhasingLabel.Text = 'Phasing :';
            app.stOrderEditFieldLabel = uilabel(app.GridLayout2);
            app.stOrderEditFieldLabel.Text = '1st Order';
            app.OrderP1 = uieditfield(app.GridLayout2, 'text');
            app.OrderP1.Value = '-0.0016';
            app.OrderEditFieldLabel = uilabel(app.GridLayout2);
            app.OrderEditFieldLabel.Text = '0 Order';
            app.OrderP0 = uieditfield(app.GridLayout2, 'text');
            app.OrderP0.Value = '-1';
            app.StepEditFieldLabel = uilabel(app.GridLayout2);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepP0 = uieditfield(app.GridLayout2, 'text');
            app.StepP0.Value = '1';
            app.StepEditField_2Label = uilabel(app.GridLayout2);
            app.StepEditField_2Label.Text = 'Step';
            app.StepP1 = uieditfield(app.GridLayout2, 'text');
            app.StepP1.Value = '0.001';
            app.OffButton = uibutton(app.GridLayout2, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Text = 'Off';
            app.RightPanel = uipanel(app.GridLayout);
            app.AxeReceived = uiaxes(app.RightPanel);
            title(app.AxeReceived, 'Received Signal (FID)')
            xlabel(app.AxeReceived, 'Time (s)')
            ylabel(app.AxeReceived, 'Amplitude (V)')
            zlabel(app.AxeReceived, 'Z')
            app.AxeReceived.ButtonDownFcn = createCallbackFcn(app, @AxeReceivedButtonDown, true);
            app.AxeFFT = uiaxes(app.RightPanel);
            title(app.AxeFFT, 'FFT')
            xlabel(app.AxeFFT, 'Frequency (Hz)')
            ylabel(app.AxeFFT, 'V/sqrt(Hz)')
            zlabel(app.AxeFFT, 'Z')
            app.AxeFFT.ButtonDownFcn = createCallbackFcn(app, @AxeFFTButtonDown, true);
            app.AxeAVRCOMP = uiaxes(app.RightPanel);
            title(app.AxeAVRCOMP, 'FID Averaged Comparison')
            xlabel(app.AxeAVRCOMP, 'Samples')
            ylabel(app.AxeAVRCOMP, 'Amplitude (V)')
            zlabel(app.AxeAVRCOMP, 'Z')
            app.AxeAVRCOMP.ButtonDownFcn = createCallbackFcn(app, @AxeAVRCOMPButtonDown, true);
            app.AxePhase = uiaxes(app.RightPanel);
            title(app.AxePhase, 'Phasing')
            xlabel(app.AxePhase, 'Chemical Shift (PPM)')
            ylabel(app.AxePhase, 'Voltage(V)')
            zlabel(app.AxePhase, 'Z')
            app.AxeCTRL = uiaxes(app.RightPanel);
            title(app.AxeCTRL, 'Alignment FID control figure')
            xlabel(app.AxeCTRL, 'Samples')
            ylabel(app.AxeCTRL, 'Amplitude (V)')
            zlabel(app.AxeCTRL, 'Z')
            app.AxeCTRL.ButtonDownFcn = createCallbackFcn(app, @AxeCTRLButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end